classdef EE430_project_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StrideEditField                 matlab.ui.control.NumericEditField
        StrideEditFieldLabel            matlab.ui.control.Label
        NumberofcomponentEditField      matlab.ui.control.NumericEditField
        NumberofcomponentEditFieldLabel  matlab.ui.control.Label
        StftwindowlengthEditField       matlab.ui.control.NumericEditField
        StftwindowlengthEditFieldLabel  matlab.ui.control.Label
        WindowDropDown                  matlab.ui.control.DropDown
        WindowDropDownLabel             matlab.ui.control.Label
        ADDButton                       matlab.ui.control.Button
        SignalNumberEditField           matlab.ui.control.NumericEditField
        SignalNumberEditFieldLabel      matlab.ui.control.Label
        TimeDeltasEditField             matlab.ui.control.NumericEditField
        TimeDeltasEditFieldLabel        matlab.ui.control.Label
        StarttimesEditField             matlab.ui.control.NumericEditField
        StarttimesEditFieldLabel        matlab.ui.control.Label
        WindowtypeDropDown              matlab.ui.control.DropDown
        WindowtypeDropDownLabel         matlab.ui.control.Label
        plotinfrequencydomainButton     matlab.ui.control.Button
        plotintimedomainButton          matlab.ui.control.Button
        DurationsEditField              matlab.ui.control.NumericEditField
        DurationsEditFieldLabel         matlab.ui.control.Label
        PhaseradianEditField            matlab.ui.control.NumericEditField
        PhaseradianEditFieldLabel       matlab.ui.control.Label
        FrequencyHzEditField            matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        AmplitudeEditField              matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        sampling_freqHzEditField        matlab.ui.control.NumericEditField
        sampling_freqHzEditFieldLabel   matlab.ui.control.Label
        GenerateSignalinvolvingmultiplecomponentsButton  matlab.ui.control.Button
        GenerateWindowedsinusoidalButton  matlab.ui.control.Button
        GenerateSinusoidalsignalButton  matlab.ui.control.Button
        File_nameEditField              matlab.ui.control.EditField
        File_nameEditFieldLabel         matlab.ui.control.Label
        RecordDurationEditField         matlab.ui.control.NumericEditField
        RecordDurationEditFieldLabel    matlab.ui.control.Label
        SamplingFrequencyEditField      matlab.ui.control.NumericEditField
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        playButton                      matlab.ui.control.Button
        RecordButton                    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        recObj % recording properties
        sound_data % recorded data itself
       

        time % time component of the generated functions
        sampling_frequency = 9600 %user defined sampling frequency
        duration = 1 % user generated signals duration in second 
        amplitude = 1 % user definded amplitude
        phase = 0 % user defined phase in radians
        frequency = 0 % user defined function frequency
        window_type = "Rectangular" % user defined window type
        start_time = 0% user defined starting time
        time_delta = 1% user defined window length
        signal_number = 0
        window = "Rectangular"
        window_length = 15
        num_of_component =1
        stride = 15 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            disp('Start speaking.')
            recordblocking(app.recObj, app.duration);
            app.sound_data = getaudiodata(app.recObj);
            app.sound_data = transpose(app.sound_data) 
            app.time = linspace(0,app.duration,app.duration * app.sampling_frequency);
            disp('End of Recording.')
        end

        % Value changed function: SamplingFrequencyEditField
        function SamplingFrequencyEditFieldValueChanged(app, event)
            value = app.SamplingFrequencyEditField.Value;
            app.sampling_frequency = value;
            app.recObj = audiorecorder(value,8,1);
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            disp('playing started.')
            sound(app.sound_data,app.sampling_frequency)
           
        end

        % Value changed function: RecordDurationEditField
        function RecordDurationEditFieldValueChanged(app, event)
            value = app.RecordDurationEditField.Value;
            app.duration = value;
            
        end

        % Value changed function: File_nameEditField
        function File_nameEditFieldValueChanged(app, event)
            value = app.File_nameEditField.Value;
            [app.sound_data, app.sampling_frequency] = audioread(value);
            app.sound_data = transpose(app.sound_data);
            size(app.sound_data)
            app.duration = length(app.sound_data)/app.sampling_frequency;
            app.time = linspace(0,app.duration,app.duration * app.sampling_frequency);
            if(length(app.time) ~= length(app.sound_data) )
            app.sound_data = app.sound_data(1:end-1);
            end

        end

        % Button pushed function: GenerateSinusoidalsignalButton
        function GenerateSinusoidalsignalButtonPushed(app, event)
            app.time = linspace(0,app.duration,app.duration * app.sampling_frequency)
       
            app.sound_data =  app.amplitude *cos(2*pi*app.frequency.*app.time + app.phase);
            
        end

        % Value changed function: FrequencyHzEditField
        function FrequencyHzEditFieldValueChanged(app, event)
            value = app.FrequencyHzEditField.Value;
            app.frequency = value;
            disp(value)
            
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            value = app.AmplitudeEditField.Value;
            app.amplitude = value;
            disp(value)
        end

        % Value changed function: sampling_freqHzEditField
        function sampling_freqHzEditFieldValueChanged(app, event)
            value = app.sampling_freqHzEditField.Value;
            app.sampling_frequency = value;
            disp(value)
        end

        % Value changed function: PhaseradianEditField
        function PhaseradianEditFieldValueChanged(app, event)
            value = app.PhaseradianEditField.Value;
            app.phase = value;
            disp(value)
        end

        % Value changed function: DurationsEditField
        function DurationsEditFieldValueChanged(app, event)
            value = app.DurationsEditField.Value;
            app.duration = value;
            disp(value)
        end

        % Button pushed function: plotintimedomainButton
        function plotintimedomainButtonPushed(app, event)
            plot(app.UIAxes,app.time,app.sound_data)
            title(app.UIAxes,"Time Domain Signal")
            ylabel(app.UIAxes,"Amplitude");
            xlabel(app.UIAxes,"Time(s)");
            grid(app.UIAxes,'on') 
        end

        % Value changed function: WindowtypeDropDown
        function WindowtypeDropDownValueChanged(app, event)
            value = app.WindowtypeDropDown.Value;
            app.window_type = value
            display(value)
        end

        % Value changed function: StarttimesEditField
        function StarttimesEditFieldValueChanged(app, event)
            value = app.StarttimesEditField.Value;
            app.start_time = value;
        end

        % Button pushed function: GenerateWindowedsinusoidalButton
        function GenerateWindowedsinusoidalButtonPushed(app, event)
            if(app.window == "Rectangular")
                app.time = linspace(0,app.duration,app.duration * app.sampling_frequency);
                app.sound_data =  app.amplitude *cos(2*pi*app.frequency.*(app.time - app.start_time) + app.phase);
                rect = zeros(1,length(app.time));
                rect(app.start_time*app.sampling_frequency:(app.start_time+app.time_delta)*app.sampling_frequency) = 1;
                %plot(app.time,app.sound_data)
                %plot(app.time,rect)
                app.sound_data = rect .* app.sound_data;
            end
            if(app.window == "Hamming")
                app.time = linspace(0,app.duration,app.duration * app.sampling_frequency);
                app.sound_data =  app.amplitude *cos(2*pi*app.frequency.*(app.time - app.start_time) + app.phase);
                hamm = zeros(1,length(app.time));
                hamming_window = linspace(0, 1 , app.sampling_frequency*app.time_delta);
                hamming_window = 25/46 - (1-25/46) * cos(2*pi*hamming_window);
                size(hamm(app.start_time*app.sampling_frequency:(app.start_time+app.time_delta)*app.sampling_frequency))
                size(hamming_window)
                hamm(app.start_time*app.sampling_frequency:(app.start_time+app.time_delta)*app.sampling_frequency-1) = hamming_window;
                app.sound_data = hamm .* app.sound_data;

            end
            
        end

        % Value changed function: TimeDeltasEditField
        function TimeDeltasEditFieldValueChanged(app, event)
            value = app.TimeDeltasEditField.Value;
            app.time_delta = value;
        end

        % Button pushed function: plotinfrequencydomainButton
        function plotinfrequencydomainButtonPushed(app, event)

            window_length = app.window_length;
            stride = app.stride;
            cycle_num = floor (app.duration * app.sampling_frequency / stride); 
            image_fft = zeros (window_length, cycle_num); 
            if strcmp(app.WindowtypeDropDown.Value, 'Rectangular') 
                for i = 1:cycle_num-window_length/stride+1
                    time_domain_cycle = app.sound_data( (i-1)*stride+1: (i-1)*stride + window_length);
                    fft_cycle = fft(time_domain_cycle);
                    fft_cycle = fftshift(fft_cycle);
                    fft_cycle_magn = abs(fft_cycle);
                    image_fft(:, i) = fft_cycle_magn;
                end 
            end 
            if strcmp(app.WindowtypeDropDown.Value, 'Hamming') 
                hamming_window = linspace(0, 1, window_length);
                hamming_window = 25/46 - (1-25/46) * cos(2*pi*hamming_window); 
                for i = 1:cycle_num-window_length/stride+1
                    time_domain_cycle = app.sound_data(  (i-1)*stride+1: (i-1)*stride + window_length ) .* (hamming_window);
                    fft_cycle = fft(time_domain_cycle);
                    fft_cycle = fftshift(fft_cycle);
                    fft_cycle_magn = abs(fft_cycle);
                    image_fft(:, i) = fft_cycle_magn;
                end 
            end
               ylimits = linspace(-app.sampling_frequency/2, app.sampling_frequency/2, window_length); 
               xlimits = linspace(window_length/(2*app.sampling_frequency), app.duration-window_length/(2*app.sampling_frequency), app.duration * app.sampling_frequency);
               image_fft = 20 * log10(image_fft);
               logarithmic_image = image(app.UIAxes, xlimits, ylimits, image_fft, 'CDataMapping','scaled');
               c = colorbar(app.UIAxes);
               c.Label.String = "Power/Frequency (dB/Hz)";
               xlabel (app.UIAxes, 'Time(s)'); 
               ylabel (app.UIAxes, 'Frequency(Hz)') 
               title(app.UIAxes, 'Spectrogram Plot of the Input Signal') 
        end

        % Drop down opening function: WindowtypeDropDown
        function WindowtypeDropDownOpening(app, event)
            
        end

        % Value changed function: SignalNumberEditField
        function SignalNumberEditFieldValueChanged(app, event)
            value = app.SignalNumberEditField.Value;
            app.signal_number = value;
        end

        % Button pushed function: ADDButton
        function ADDButtonPushed(app, event)
            if app.signal_number == 1
            app.time = linspace(0,app.duration,app.duration * app.sampling_frequency);
            app.sound_data =  app.amplitude *cos(2*pi*app.frequency.*app.time + app.phase);      
            end
            if app.signal_number > 1 && app.signal_number <= app.num_of_component
            
            app.sound_data = app.sound_data + app.amplitude *cos(2*pi*app.frequency.*app.time + app.phase);
            
            end

       
        end

        % Value changed function: WindowDropDown
        function WindowDropDownValueChanged(app, event)
            value = app.WindowDropDown.Value;
            app.window = value;
        end

        % Value changed function: StftwindowlengthEditField
        function StftwindowlengthEditFieldValueChanged(app, event)
            value = app.StftwindowlengthEditField.Value;
            app.window_length = value;
        end

        % Value changed function: NumberofcomponentEditField
        function NumberofcomponentEditFieldValueChanged(app, event)
            value = app.NumberofcomponentEditField.Value;
            app.num_of_component = value;
        end

        % Value changed function: StrideEditField
        function StrideEditFieldValueChanged(app, event)
            value = app.StrideEditField.Value;
            app.stride = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.playButton = uibutton(app.UIFigure, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Text = 'play';
            app.SamplingFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequencyEditFieldValueChanged, true);
            app.RecordDurationEditFieldLabel = uilabel(app.UIFigure);
            app.RecordDurationEditFieldLabel.Text = 'Record Duration';
            app.RecordDurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.RecordDurationEditField.ValueChangedFcn = createCallbackFcn(app, @RecordDurationEditFieldValueChanged, true);
            app.File_nameEditFieldLabel = uilabel(app.UIFigure);
            app.File_nameEditFieldLabel.Text = 'File_name';
            app.File_nameEditField = uieditfield(app.UIFigure, 'text');
            app.File_nameEditField.ValueChangedFcn = createCallbackFcn(app, @File_nameEditFieldValueChanged, true);
            app.GenerateSinusoidalsignalButton = uibutton(app.UIFigure, 'push');
            app.GenerateSinusoidalsignalButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSinusoidalsignalButtonPushed, true);
            app.GenerateSinusoidalsignalButton.Text = {'Generate Sinusoidal signal'; ''};
            app.GenerateWindowedsinusoidalButton = uibutton(app.UIFigure, 'push');
            app.GenerateWindowedsinusoidalButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateWindowedsinusoidalButtonPushed, true);
            app.GenerateWindowedsinusoidalButton.Text = {'Generate Windowed sinusoidal'; ''};
            app.GenerateSignalinvolvingmultiplecomponentsButton = uibutton(app.UIFigure, 'push');
            app.GenerateSignalinvolvingmultiplecomponentsButton.Text = {'Generate Signal involving multiple components'; ''};
            app.sampling_freqHzEditFieldLabel = uilabel(app.UIFigure);
            app.sampling_freqHzEditFieldLabel.Text = 'sampling_freq(Hz)';
            app.sampling_freqHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.sampling_freqHzEditField.ValueChangedFcn = createCallbackFcn(app, @sampling_freqHzEditFieldValueChanged, true);
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.FrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency(Hz)';
            app.FrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzEditFieldValueChanged, true);
            app.PhaseradianEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseradianEditFieldLabel.Text = 'Phase(radian)';
            app.PhaseradianEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseradianEditField.ValueChangedFcn = createCallbackFcn(app, @PhaseradianEditFieldValueChanged, true);
            app.DurationsEditFieldLabel = uilabel(app.UIFigure);
            app.DurationsEditFieldLabel.Text = 'Duration(s)';
            app.DurationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationsEditField.ValueChangedFcn = createCallbackFcn(app, @DurationsEditFieldValueChanged, true);
            app.plotintimedomainButton = uibutton(app.UIFigure, 'push');
            app.plotintimedomainButton.ButtonPushedFcn = createCallbackFcn(app, @plotintimedomainButtonPushed, true);
            app.plotintimedomainButton.Text = 'plot in time domain';
            app.plotinfrequencydomainButton = uibutton(app.UIFigure, 'push');
            app.plotinfrequencydomainButton.ButtonPushedFcn = createCallbackFcn(app, @plotinfrequencydomainButtonPushed, true);
            app.plotinfrequencydomainButton.Text = 'plot in frequency domain';
            app.WindowtypeDropDownLabel = uilabel(app.UIFigure);
            app.WindowtypeDropDownLabel.Text = 'Window type';
            app.WindowtypeDropDown = uidropdown(app.UIFigure);
            app.WindowtypeDropDown.Items = {'Rectangular', 'Hamming'};
            app.WindowtypeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @WindowtypeDropDownOpening, true);
            app.WindowtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @WindowtypeDropDownValueChanged, true);
            app.WindowtypeDropDown.Value = 'Hamming';
            app.StarttimesEditFieldLabel = uilabel(app.UIFigure);
            app.StarttimesEditFieldLabel.Text = 'Start time(s)';
            app.StarttimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.StarttimesEditField.ValueChangedFcn = createCallbackFcn(app, @StarttimesEditFieldValueChanged, true);
            app.TimeDeltasEditFieldLabel = uilabel(app.UIFigure);
            app.TimeDeltasEditFieldLabel.Text = 'Time Delta(s)';
            app.TimeDeltasEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeDeltasEditField.ValueChangedFcn = createCallbackFcn(app, @TimeDeltasEditFieldValueChanged, true);
            app.SignalNumberEditFieldLabel = uilabel(app.UIFigure);
            app.SignalNumberEditFieldLabel.Text = 'Signal Number';
            app.SignalNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.SignalNumberEditField.ValueChangedFcn = createCallbackFcn(app, @SignalNumberEditFieldValueChanged, true);
            app.ADDButton = uibutton(app.UIFigure, 'push');
            app.ADDButton.ButtonPushedFcn = createCallbackFcn(app, @ADDButtonPushed, true);
            app.ADDButton.Text = 'ADD';
            app.WindowDropDownLabel = uilabel(app.UIFigure);
            app.WindowDropDownLabel.Text = 'Window';
            app.WindowDropDown = uidropdown(app.UIFigure);
            app.WindowDropDown.Items = {'Rectangular', 'Hamming'};
            app.WindowDropDown.ValueChangedFcn = createCallbackFcn(app, @WindowDropDownValueChanged, true);
            app.WindowDropDown.Value = 'Rectangular';
            app.StftwindowlengthEditFieldLabel = uilabel(app.UIFigure);
            app.StftwindowlengthEditFieldLabel.Text = 'Stft window length';
            app.StftwindowlengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.StftwindowlengthEditField.ValueChangedFcn = createCallbackFcn(app, @StftwindowlengthEditFieldValueChanged, true);
            app.NumberofcomponentEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofcomponentEditFieldLabel.Text = 'Number of component';
            app.NumberofcomponentEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofcomponentEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofcomponentEditFieldValueChanged, true);
            app.StrideEditFieldLabel = uilabel(app.UIFigure);
            app.StrideEditFieldLabel.Text = 'Stride';
            app.StrideEditField = uieditfield(app.UIFigure, 'numeric');
            app.StrideEditField.ValueChangedFcn = createCallbackFcn(app, @StrideEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EE430_project_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end